classdef ImageEnhancerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        DownloadButton  matlab.ui.control.Button
        LoadButton      matlab.ui.control.Button
        EnhanceButton   matlab.ui.control.Button
        ResultsText     matlab.ui.control.TextArea
        Label           matlab.ui.control.Label
        AnalysisAxes    matlab.ui.control.UIAxes
        EnhancedAxes    matlab.ui.control.UIAxes
        OriginalAxes    matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    OriginalImage   % 儲存原始影像數據
    EnhancedImage   % 儲存強化後影像數據
    MarkedImage     % 儲存分析後影像數據
    InputFilePath   % 儲存原始影像路徑
    Scores          % 儲存評分結果
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 關閉座標區的刻度和工具列
disableDefaultInteractivity(app.OriginalAxes);
disableDefaultInteractivity(app.EnhancedAxes);
disableDefaultInteractivity(app.AnalysisAxes);

app.OriginalAxes.XTick = []; app.OriginalAxes.YTick = [];
app.EnhancedAxes.XTick = []; app.EnhancedAxes.YTick = [];
app.AnalysisAxes.XTick = []; app.AnalysisAxes.YTick = [];

app.ResultsText.Value = '請先載入影像...';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % --- 【關鍵修正】---
            % 將多個副檔名合併到同一個篩選器中，讓它們預設同時顯示
            % 格式：{'*.png;*.jpg;*.bmp', '篩選器標籤'}
            filter = {'*.png;*.jpg;*.bmp', '支援的圖片檔 (*.png, *.jpg, *.bmp)'; ...
                      '*.*', '所有檔案 (*.*)'};
                      
            [fileName, filePath] = uigetfile(filter, '請選擇一張影像');
            
            % --- (這是修復 App 最小化問題的SOP) ---
            % 強制將 App 視窗拉回最前面
            figure(app.UIFigure); 

            if isequal(fileName, 0)
                return; % 使用者取消
            end
            
            app.InputFilePath = fullfile(filePath, fileName);
            
            try
                app.OriginalImage = imread(app.InputFilePath);
                
                % 檢查是否為 RGB
                if size(app.OriginalImage, 3) ~= 3
                     app.ResultsText.Value = "錯誤：影像必須為 RGB 彩色影像。";
                     uialert(app.UIFigure, '影像必須為 RGB 彩色影像。', '載入失敗', 'Icon', 'error');
                     return;
                end
                
                % 顯示原始影像
                imshow(app.OriginalImage, 'Parent', app.OriginalAxes);
                
                % 清空其他區域
                cla(app.EnhancedAxes);
                cla(app.AnalysisAxes);
                
                % (改用 string)
                app.ResultsText.Value = sprintf("影像已載入：%s\n請點擊「開始強化」。", fileName);
                app.EnhanceButton.Enable = 'on';
                app.DownloadButton.Enable = 'off';
                
                % 清除舊數據
                app.EnhancedImage = [];
                app.MarkedImage = [];
                app.Scores = [];
                
            catch ME
                % (改用 string)
                errorMessage = "載入影像失敗：" + string(ME.message); 
                app.ResultsText.Value = errorMessage;
                uialert(app.UIFigure, errorMessage, '錯誤', 'Icon', 'error');
            end
        end

        % Button pushed function: EnhanceButton
        function EnhanceButtonPushed(app, event)
            if isempty(app.OriginalImage)
                app.ResultsText.Value = "請先載入影像。"; % 使用 string
                return;
            end
            
            % 顯示進度條
            d = uiprogressdlg(app.UIFigure, 'Title', '請稍候', ...
                'Message', '正在強化影像中...', 'Indeterminate', 'on');
            
            app.EnhanceButton.Enable = 'off';
            app.LoadButton.Enable = 'off';
            drawnow; % 立即更新介面
            
            try
                % 1. 判斷亮度
                app.ResultsText.Value = "步驟 1/5：計算平均亮度..."; % 使用 string (設定)
                drawnow;
                avgBrightness = calculate_brightness(app.OriginalImage);
                
                % 2. 決定優化指標
                if avgBrightness > 40
                    metric = 'PCQI';
                    app.ResultsText.Value = sprintf("亮度 (%.2f) > 40。\n使用 PCQI 作為優化指標。", avgBrightness); 
                else
                    metric = 'BRISQUE';
                    app.ResultsText.Value = sprintf("亮度 (%.2f) <= 40。\n使用 BRISQUE 作為優化指標。", avgBrightness);
                end
                drawnow;
                
                % 3. 執行 MPA 優化與強化
                app.ResultsText.Value = [string(app.ResultsText.Value); "步驟 2/5：執行 MPA 優化... (這可能需要幾分鐘)"]; 
                drawnow;
                [app.EnhancedImage, best_a, best_b, best_fitness] = run_mpa_optimization(app.OriginalImage, metric);
                app.ResultsText.Value = [string(app.ResultsText.Value); "步驟 3/5：優化完成。"];
                drawnow;
                
                % 4. 執行 BRISQUE 改善分析
                app.ResultsText.Value = [string(app.ResultsText.Value); "步驟 4/5：分析畫質改善區域..."];
                drawnow;
                
                app.MarkedImage = analyze_improvement(app.OriginalImage, app.EnhancedImage);
                
                % 5. 計算三張圖的 PCQI 和 BRISQUE 分數
                app.ResultsText.Value = [string(app.ResultsText.Value); "步驟 5/5：計算評估分數..."];
                drawnow;

                % 計算 BRISQUE (維持原樣即可，內建函數支援 uint8)
                score_orig_brisque = brisque(app.OriginalImage);
                score_enh_brisque = brisque(app.EnhancedImage);
                score_mark_brisque = brisque(app.MarkedImage);

                % 將圖片強制轉為 double 類型，再餵給 PCQI 算，防止 uint8 溢位爆掉
                inputGray = double(rgb2gray(app.OriginalImage));
                enhancedGray = double(rgb2gray(app.EnhancedImage));
                markedGray = double(rgb2gray(app.MarkedImage));

                % 呼叫 PCQI.m 進行計算
                score_orig_pcqi = PCQI(inputGray, inputGray); % 這時候與自身比較，絕對會精準等於 1.0！
                score_enh_pcqi = PCQI(inputGray, enhancedGray);
                score_mark_pcqi = PCQI(inputGray, markedGray);

                % 儲存分數至 Table
                app.Scores = table( ...
                    ["Original"; "Enhanced"; "Marked"], ...
                    [score_orig_pcqi; score_enh_pcqi; score_mark_pcqi], ...
                    [score_orig_brisque; score_enh_brisque; score_mark_brisque], ...
                     'VariableNames', {'Image', 'PCQI', 'BRISQUE'});
                
                % 顯示結果
                imshow(app.EnhancedImage, 'Parent', app.EnhancedAxes);
                imshow(app.MarkedImage, 'Parent', app.AnalysisAxes);
                
                % 更新文字區域 (修改版：讓 PCQI 和 BRISQUE 同時顯示)
                resultString = [ ...
                    sprintf("強化完成！ (使用 %s 優化)", metric); ...
                    "--- BRISQUE 分數 (越低越好) ---"; ...
                    sprintf("原始影像： %.2f ➔ 強化圖片： %.2f", score_orig_brisque, score_enh_brisque); ...
                    "--- PCQI 分數 (越高越好) ---"; ...
                    sprintf("原始影像： %.2f ➔ 強化圖片： %.2f", score_orig_pcqi, score_enh_pcqi) ...
                    ];

                app.ResultsText.Value = resultString;
                
                app.ResultsText.Value = resultString; % 指派 string 陣列
                
                % 啟用下載按鈕
                app.DownloadButton.Enable = 'on';
                
            catch ME
                % --- 【關鍵修正】 ---
                % 使用 string() 強制轉換 ME.message，以防它是 cell
                errorMessage = "處理失敗：" + string(ME.message) + newline + ...
                               "錯誤發生在檔案: " + string(ME.stack(1).name) + newline + ...
                               "錯誤行數: " + num2str(ME.stack(1).line);
                
                uialert(app.UIFigure, errorMessage, '錯誤');
                app.ResultsText.Value = errorMessage; % 指派修正後的 error string
            end
            
            % 關閉進度條
            close(d);
            app.EnhanceButton.Enable = 'off';
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            if isempty(app.EnhancedImage)
                uialert(app.UIFigure, "沒有可下載的影像。", "錯誤", 'Icon', 'error'); % 錯誤提示使用 'error' 圖示
                return;
            end
            
            % 讓使用者選擇儲存的資料夾
            folderName = uigetdir(pwd, '請選擇儲存結果的資料夾');
            figure(app.UIFigure);
            if isequal(folderName, 0)
                return; % 使用者取消
            end
            
            [~, baseName, ~] = fileparts(app.InputFilePath);
            
            try
                % --- 【依照您的要求】 ---
                % 只儲存強化後的影像
                
                % 建立儲存路徑
                enhancedImageFileName = fullfile(folderName, [baseName '_Enhanced.png']);
                
                % 儲存強化後的影像
                imwrite(app.EnhancedImage, enhancedImageFileName);
                
                % (儲存原始影像、分析影像、.txt 檔案的SOP皆已移除)
                
                
                % --- 【關鍵修正】 ---
                % (改用 string 以確保穩定)
                alertMessage = "強化後的影像已成功儲存至：" + newline + string(folderName);
                
                % 新增 'Icon', 'success' 參數，使其顯示為綠色打勾
                uialert(app.UIFigure, alertMessage, "儲存完畢", 'Icon', 'success');
                
            catch ME
                % (改用 string 以确保穩定)
                errorMessage = "儲存失敗：" + string(ME.message);
                
                % 錯誤提示使用 'error' 圖示
                uialert(app.UIFigure, errorMessage, "錯誤", 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, '原始影像')
            app.OriginalAxes.XTick = [];
            app.OriginalAxes.YTick = [];
            app.OriginalAxes.Tag = 'OriginalAxes';
            app.EnhancedAxes = uiaxes(app.UIFigure);
            title(app.EnhancedAxes, '強化後影像')
            app.EnhancedAxes.XTick = [];
            app.EnhancedAxes.YTick = [];
            app.EnhancedAxes.Tag = 'EnhancedAxes';
            app.AnalysisAxes = uiaxes(app.UIFigure);
            title(app.AnalysisAxes, '畫質改善分析')
            app.AnalysisAxes.XTick = [];
            app.AnalysisAxes.YTick = [];
            app.AnalysisAxes.Tag = 'AnalysisAxes';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ResultsText = uitextarea(app.UIFigure);
            app.ResultsText.Tag = 'ResultsText';
            app.ResultsText.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.EnhanceButton = uibutton(app.Panel, 'push');
            app.EnhanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.EnhanceButton.Tag = 'EnhanceButton';
            app.EnhanceButton.Enable = 'off';
            app.EnhanceButton.Text = '開始強化';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tag = 'LoadButton';
            app.LoadButton.Text = '載入圖片';
            app.DownloadButton = uibutton(app.Panel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Tag = 'DownloadButton';
            app.DownloadButton.Enable = 'off';
            app.DownloadButton.Text = '下載結果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEnhancerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end